classdef AUV_FEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PanelConsole                matlab.ui.container.Panel
        ConsoleLog                  matlab.ui.control.TextArea
        PanelMesh                   matlab.ui.container.Panel
        RightOptions                matlab.ui.container.Panel
        WaveAmplitude               matlab.ui.control.NumericEditField
        ScaleLabel_7                matlab.ui.control.Label
        IncidentThetaListEditField  matlab.ui.control.EditField
        IncidentListLabel           matlab.ui.control.Label
        PeriodListEditField         matlab.ui.control.EditField
        PeriodListEditFieldLabel    matlab.ui.control.Label
        ImportPhisButton            matlab.ui.control.Button
        WaveNumber                  matlab.ui.control.NumericEditField
        ScaleLabel_6                matlab.ui.control.Label
        Wavelength                  matlab.ui.control.NumericEditField
        ScaleLabel_5                matlab.ui.control.Label
        WaterDensity                matlab.ui.control.NumericEditField
        ScaleLabel_4                matlab.ui.control.Label
        Gravity                     matlab.ui.control.NumericEditField
        ScaleLabel_3                matlab.ui.control.Label
        LeftOptions                 matlab.ui.container.Panel
        ImportPointMassesButton     matlab.ui.control.Button
        ScaleMeshField              matlab.ui.control.NumericEditField
        ScaleLabel_2                matlab.ui.control.Label
        HminField                   matlab.ui.control.NumericEditField
        HminHmaxLabel               matlab.ui.control.Label
        HmaxField                   matlab.ui.control.NumericEditField
        HminHmaxLabel_3             matlab.ui.control.Label
        ScaleGeometryField          matlab.ui.control.NumericEditField
        ScaleLabel                  matlab.ui.control.Label
        Normals6DCheckBox           matlab.ui.control.CheckBox
        NormalsCheckBox             matlab.ui.control.CheckBox
        ImportMeshButton            matlab.ui.control.Button
        MeshGeometryButton          matlab.ui.control.Button
        ViewGeometryButton          matlab.ui.control.Button
        ImportGeometryButton        matlab.ui.control.Button
        MeshPlot                    matlab.ui.control.UIAxes
        PanelRAO                    matlab.ui.container.Panel
        PanelRAOPlotOptions         matlab.ui.container.Panel
        OrLabel                     matlab.ui.control.Label
        DisplayLabel                matlab.ui.control.Label
        HeaveSurgeSwayCheckBox      matlab.ui.control.CheckBox
        YawRollPitchCheckBox        matlab.ui.control.CheckBox
        ExportResponsesButton       matlab.ui.control.Button
        ExportPhisButton            matlab.ui.control.Button
        CalculateRAOsButton         matlab.ui.control.Button
        CalculatePhisButton         matlab.ui.control.Button
        PanelRAOPlot                matlab.ui.container.Panel
    end

    properties (Access = private)
        GeometryFigureNumber % the number of handle of the geometry figure
        GeometryScale % value that scales geometry size
        LastScaleGeometryValue % the previous value of ScaleGeometryField
        LastScaleMeshValue % the previous value of ScaleMeshField
        Mesh % mesh of submerged object
        Model % model of submerged object 
        Periods % a list of periods to evaluate the RAO at
        PointMasses % a list of pointmasses (~,4) [m,x,y,z]
        RAOAxes % a list of plots in the TiledLayoutRAO object
        Thetas % a list of incident wave angles
        TiledLayoutRAO % a tiled layout for plotting RAO
        TrimeshScuffedFigureNumber % trimesh opens a figure for no reason
        VelocityPotentials % a list of velocities potentials
        VelocityPotentialPeriods % a list of velocity potential periods
    end

    methods (Access = private)
        
        function [] = initPanelRAO(app)
            % Creates Tiled Layout in Panel
            app.TiledLayoutRAO = tiledlayout(app.PanelRAOPlot,3,1);
            app.TiledLayoutRAO.TileSpacing = 'compact';
            xlabel(app.TiledLayoutRAO,'Period');
            ylabel(app.TiledLayoutRAO,'RAO');

            % Creates Axes in Tiled Layout
            app.RAOAxes = zeros(3,1);
            app.RAOAxes(1) = nexttile(app.TiledLayoutRAO,1);
            app.RAOAxes(2) = nexttile(app.TiledLayoutRAO,2);
            app.RAOAxes(3) = nexttile(app.TiledLayoutRAO,3);
            updateRAOAxesTitles(app);
            linkaxes([app.RAOAxes(1),app.RAOAxes(2),app.RAOAxes(3)],'x');
        end
        
        function [] = log(app,text)
            app.ConsoleLog.Value = cellstr([app.ConsoleLog.Value(:)',text]);
            pause(0.00000001);
            uiprogressdlg(app.UIFigure)
            scroll(app.ConsoleLog, 'bottom');
        end

        function [] = updateGeometryPlot(app)
            % Only updates geometry when plot is open
            if (ishandle(app.GeometryFigureNumber))
                figure(app.GeometryFigureNumber);
                try
                    pdegplot(app.Model,"FaceLabels","on");
                catch error                
                    log(app,error.message);
                end
            end
        end

        function [] = updateMeshPlot(app,mesh)
            % Trimesh opens an empty figure before 'Parent' steals its
            %  draw focus. This opens the figure empty itself to then
            %  automatically close it afterward.
            figure(app.TrimeshScuffedFigureNumber);
            try
                trimesh(mesh,'Parent',app.MeshPlot);
            catch
                close(app.TrimeshScuffedFigureNumber);
                return;
            end
            close(app.TrimeshScuffedFigureNumber);

            hold(app.MeshPlot,"on");
            axis(app.MeshPlot,"equal");

            % Finds each triangle's center point and face normal vectors
            CP  = incenter(mesh);
            FN = faceNormal(mesh);
            
            if app.NormalsCheckBox.Value == true
                % Plotes the triangulation object's normals
                quiver3(CP(:,1),CP(:,2),CP(:,3), ...
                     FN(:,1),FN(:,2),FN(:,3),0.5,'color','r', ...
                     'Parent',app.MeshPlot);
            end

            if app.Normals6DCheckBox.Value == true
                % Creates each triangle's 6-dimensional normal vector
                FN6 = normal6DOF(CP,FN);
                
                % Plotes the triangulation object's rotational normals
                quiver3(app.MeshPlot,CP(:,1),CP(:,2),CP(:,3), ...
                     FN6(:,4),FN6(:,5),FN6(:,6),0.5,'color','r', ...
                     'Parent',app.MeshPlot);
            end

            hold(app.MeshPlot,"off");
        end
        
        function [] = updateRAOAxesTitles(app)
            if app.HeaveSurgeSwayCheckBox.Value == true
                title(app.RAOAxes(1),'Heave');
                title(app.RAOAxes(2),'Surge');
                title(app.RAOAxes(3),'Sway');
            else
                title(app.RAOAxes(1),'Yaw');
                title(app.RAOAxes(2),'Roll');
                title(app.RAOAxes(3),'Pitch');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            initPanelRAO(app);
            app.GeometryFigureNumber = 1;
            app.TrimeshScuffedFigureNumber = 2;
            app.LastScaleGeometryValue = app.ScaleGeometryField.Value;
            app.LastScaleMeshValue = app.ScaleMeshField.Value;
            axis(app.MeshPlot, "equal");
            app.WaveNumber.Value = 2*pi()/app.Wavelength.Value;
            app.PointMasses = [0,0,0,0];
            app.Periods = str2num(app.PeriodListEditField.Value);
            app.YawRollPitchCheckBox.Value = ~app.HeaveSurgeSwayCheckBox.Value;
            app.ConsoleLog.Value = "Welcome to AUV_FEA_Solver 1.0";
            uiprogressdlg(app.UIFigure)
            app.VelocityPotentials = [];
            app.VelocityPotentialPeriods = [];
        end

        % Button pushed function: ImportGeometryButton
        function ImportGeometryButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            figure(app.UIFigure); % requests focus back

            try  
                geometryPath = append(path,file);

                model = createpde();
                importGeometry(model,geometryPath);
                scale(model.Geometry, app.ScaleGeometryField.Value);
                app.Model = model;

                log(app,append("Imported: ",file));

                figure(app.GeometryFigureNumber);
                updateGeometryPlot(app);
            catch error                
                log(app,error.message);
            end
        end

        % Button pushed function: ImportPointMassesButton
        function ImportPointMassesButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                pointMasses = readmatrix(append(file,path));

                [~,col] = size(pointMasses);
                if col == 4
                    app.PointMasses = pointMasses;

                    log(app,append("Imported Point Masses: ",file));
                else
                    log(app,sprintf("Failed to import %s.\nIncorrect dimensions.",file));
                end
            catch error                
                log(app,error.message);
            end
        end

        % Button pushed function: ImportMeshButton
        function ImportMeshButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            figure(app.UIFigure); % requests focus back

            try
                mesh = stlread(append(path,file));
                mesh = triangulation(mesh.ConnectivityList, ...
                    mesh.Points*app.ScaleMeshField.Value);
                updateMeshPlot(app,mesh);
                app.Mesh = mesh;

                log(app,append("Imported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Button pushed function: ImportPhisButton
        function ImportPhisButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                vpsm = readmatrix(append(file,path)); % imports velocity potentials
                
                N = vpsm(1,1); % first value list the length of velocity potentials
                [nT,~] = size(vpsm);
                nT = (nT - 1) / 6; % one row for each dof of each period except first row which are the periods

                velocityPotentials = zeros(6,N,nT);
                for j = 1:nT
                    for i = 1:6
                        velocityPotentials(i,:,j) = vpsm(6*j + i - 5,:);
                    end
                end

                app.VelocityPotentials = velocityPotentials;
                app.VelocityPotentialPeriods = vpsm(1,2:nT + 1);

                log(app,append("Imported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Button pushed function: ViewGeometryButton
        function ViewGeometryButtonPushed(app, event)
            figure(app.GeometryFigureNumber);
            updateGeometryPlot(app);
        end

        % Button pushed function: MeshGeometryButton
        function MeshGeometryButtonPushed(app, event)
            try
                generateMesh(app.Model, 'GeometricOrder','linear','Hmin',app.HminField.Value,'Hmax',app.HmaxField.Value);
                
                % Makes a triangulation object of Model.Mesh's surface
                mto = triangulation(app.Model.Mesh.Elements.',app.Model.Mesh.Nodes.');                
                [T,P] = freeBoundary(mto);
                mesh = triangulation(T,P);

                updateMeshPlot(app,mesh);

                app.Mesh = mesh;
                
                log(app,sprintf("Sucessfully meshed geometry.\nHmin: %f, Hmax: %f",app.HminField.Value,app.HmaxField.Value));
            catch error             
                log(app,error.message);
            end
        end

        % Button pushed function: CalculatePhisButton
        function CalculatePhisButtonPushed(app, event)
            T = setdiff(app.Periods,app.VelocityPotentialPeriods);
            g = app.Gravity.Value;
            mesh = app.Mesh;
            
            try
                % Finds each triangle's center point and face normal vectors
                CP  = incenter(mesh);
                FN = faceNormal(mesh);
            catch
                log(app,"Add a mesh first.");
                return
            end
            
            [~,nT] = size(T);
            [N,~] = size(CP);

            % Defines a list of N triangles so that parfor can nicely slice data
            Tri = zeros(3,3,N);
            for j = 1:N
                Tri(:,:,j) = mesh.Points(mesh.ConnectivityList(j,:),:);
            end

            runseconds = N*N*0.00018*3*nT;
            runminutes = runseconds/60;
            log(app,sprintf("Estimated Runtime ~ %.0fs or %.1fmin",runseconds,runminutes));

            % Calculates veloctiy potentials for each period 
            phis = zeros(6,N,nT);
            for j = 1:nT
                log(app,sprintf("----%.0f%%----",(j - 1)/nT*100));
                K = (2*pi()/T(j))^2/g; % sets K(w) = w^2/g
                phis(:,:,j) = velocityPotential(Tri,CP,FN,K);
            end
            log(app,"----Done----");

            % Combine all periods
            [~,nTOld] = size(app.VelocityPotentialPeriods);
            app.VelocityPotentialPeriods = [app.VelocityPotentialPeriods,T];

            % Combine all velocity potentials
            phisOld = app.VelocityPotentials;
            app.VelocityPotentials = zeros(6,N,nT+nTOld);
            app.VelocityPotentials(:,:,1:nTOld) = phisOld;
            app.VelocityPotentials(:,:,(nTOld + 1):(nTOld + nT)) = phis;
        end

        % Button pushed function: CalculateRAOsButton
        function CalculateRAOsButtonPushed(app, event)
            
        end

        % Value changed function: NormalsCheckBox
        function NormalsCheckBoxValueChanged(app, event)
            updateMeshPlot(app,app.Mesh);   
        end

        % Value changed function: Normals6DCheckBox
        function Normals6DCheckBoxValueChanged(app, event)
                updateMeshPlot(app,app.Mesh);
        end

        % Value changed function: PeriodListEditField
        function PeriodListEditFieldValueChanged(app, event)
            try
                app.Periods = str2num(app.PeriodListEditField.Value);
            catch error                
                log(app,error.message);
            end
        end

        % Value changed function: IncidentThetaListEditField
        function IncidentThetaListEditFieldValueChanged(app, event)
            try
                app.Thetas = str2num(app.IncidentThetaListEditField.Value);
            catch error                
                log(app,error.message);
            end
        end

        % Value changed function: ScaleGeometryField
        function ScaleGeometryFieldValueChanged(app, event)
            value = app.ScaleGeometryField.Value/app.LastScaleGeometryValue;
            try
                scale(app.Model.Geometry, value);
                app.LastScaleGeometryValue = app.ScaleGeometryField.Value;
                updateGeometryPlot(app);
            catch
            end
        end

        % Value changed function: ScaleMeshField
        function ScaleMeshFieldValueChanged(app, event)
            value = app.ScaleMeshField.Value/app.LastScaleMeshValue;
            try
                app.Mesh = triangulation(app.Mesh.ConnectivityList, ...
                    app.Mesh.Points*value);
                app.LastScaleMeshValue = app.ScaleMeshField.Value;
                updateMeshPlot(app,app.Mesh);
            catch
            end
        end

        % Value changed function: Wavelength
        function WavelengthValueChanged(app, event)
            app.WaveNumber.Value = 2*pi()/app.Wavelength.Value;
        end

        % Value changed function: WaveNumber
        function WaveNumberValueChanged(app, event)
            app.Wavelength.Value = 2*pi()/app.WaveNumber.Value;
        end

        % Value changed function: HeaveSurgeSwayCheckBox
        function HeaveSurgeSwayCheckBoxValueChanged(app, event)
            app.YawRollPitchCheckBox.Value = ~app.HeaveSurgeSwayCheckBox.Value;
            updateRAOAxesTitles(app);
        end

        % Value changed function: YawRollPitchCheckBox
        function YawRollPitchCheckBoxValueChanged(app, event)
            app.HeaveSurgeSwayCheckBox.Value = ~app.YawRollPitchCheckBox.Value;
            updateRAOAxesTitles(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelRAO = uipanel(app.UIFigure);
            app.PanelRAOPlot = uipanel(app.PanelRAO);
            app.PanelRAOPlotOptions = uipanel(app.PanelRAO);
            app.PanelRAOPlotOptions.Title = 'RAO Panel';
            app.CalculatePhisButton = uibutton(app.PanelRAOPlotOptions, 'push');
            app.CalculatePhisButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePhisButtonPushed, true);
            app.CalculatePhisButton.Text = 'Calculate Φs';
            app.CalculateRAOsButton = uibutton(app.PanelRAOPlotOptions, 'push');
            app.CalculateRAOsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRAOsButtonPushed, true);
            app.CalculateRAOsButton.Text = 'Calculate RAOs';
            app.ExportPhisButton = uibutton(app.PanelRAOPlotOptions, 'push');
            app.ExportPhisButton.Text = 'Export Φs';
            app.ExportResponsesButton = uibutton(app.PanelRAOPlotOptions, 'push');
            app.ExportResponsesButton.Text = 'Export Responses';
            app.YawRollPitchCheckBox = uicheckbox(app.PanelRAOPlotOptions);
            app.YawRollPitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @YawRollPitchCheckBoxValueChanged, true);
            app.YawRollPitchCheckBox.Text = 'Yaw, Roll, Pitch';
            app.HeaveSurgeSwayCheckBox = uicheckbox(app.PanelRAOPlotOptions);
            app.HeaveSurgeSwayCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeaveSurgeSwayCheckBoxValueChanged, true);
            app.HeaveSurgeSwayCheckBox.Text = 'Heave, Surge, Sway';
            app.HeaveSurgeSwayCheckBox.Value = true;
            app.DisplayLabel = uilabel(app.PanelRAOPlotOptions);
            app.DisplayLabel.Text = 'Display';
            app.OrLabel = uilabel(app.PanelRAOPlotOptions);
            app.OrLabel.Text = 'Or';
            app.PanelMesh = uipanel(app.UIFigure);
            app.MeshPlot = uiaxes(app.PanelMesh);
            title(app.MeshPlot, 'Mesh Plot')
            xlabel(app.MeshPlot, 'X')
            ylabel(app.MeshPlot, 'Y')
            zlabel(app.MeshPlot, 'Z')
            app.MeshPlot.XLim = [-1 0.5];
            app.MeshPlot.YLim = [-0.5 0.5];
            app.MeshPlot.ZLim = [-0.5 0];
            app.LeftOptions = uipanel(app.PanelMesh);
            app.LeftOptions.Title = 'Mesh Options';
            app.ImportGeometryButton = uibutton(app.LeftOptions, 'push');
            app.ImportGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGeometryButtonPushed, true);
            app.ImportGeometryButton.Text = 'Import Geometry';
            app.ViewGeometryButton = uibutton(app.LeftOptions, 'push');
            app.ViewGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewGeometryButtonPushed, true);
            app.ViewGeometryButton.Text = 'View Geometry';
            app.MeshGeometryButton = uibutton(app.LeftOptions, 'push');
            app.MeshGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @MeshGeometryButtonPushed, true);
            app.MeshGeometryButton.Text = 'Mesh Geometry';
            app.ImportMeshButton = uibutton(app.LeftOptions, 'push');
            app.ImportMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMeshButtonPushed, true);
            app.ImportMeshButton.Text = 'Import Mesh';
            app.NormalsCheckBox = uicheckbox(app.LeftOptions);
            app.NormalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalsCheckBoxValueChanged, true);
            app.NormalsCheckBox.Text = 'Show Normals';
            app.Normals6DCheckBox = uicheckbox(app.LeftOptions);
            app.Normals6DCheckBox.ValueChangedFcn = createCallbackFcn(app, @Normals6DCheckBoxValueChanged, true);
            app.Normals6DCheckBox.Text = 'Show 6D Normals';
            app.ScaleLabel = uilabel(app.LeftOptions);
            app.ScaleLabel.Text = 'Scale Geometry';
            app.ScaleGeometryField = uieditfield(app.LeftOptions, 'numeric');
            app.ScaleGeometryField.ValueChangedFcn = createCallbackFcn(app, @ScaleGeometryFieldValueChanged, true);
            app.ScaleGeometryField.Value = 0.001;
            app.HminHmaxLabel_3 = uilabel(app.LeftOptions);
            app.HminHmaxLabel_3.Text = 'Hmax';
            app.HmaxField = uieditfield(app.LeftOptions, 'numeric');
            app.HmaxField.Value = 0.1;
            app.HminHmaxLabel = uilabel(app.LeftOptions);
            app.HminHmaxLabel.Text = 'Hmin';
            app.HminField = uieditfield(app.LeftOptions, 'numeric');
            app.HminField.Value = 0.01;
            app.ScaleLabel_2 = uilabel(app.LeftOptions);
            app.ScaleLabel_2.Text = 'Scale Imported Mesh';
            app.ScaleMeshField = uieditfield(app.LeftOptions, 'numeric');
            app.ScaleMeshField.ValueChangedFcn = createCallbackFcn(app, @ScaleMeshFieldValueChanged, true);
            app.ScaleMeshField.Value = 0.1;
            app.ImportPointMassesButton = uibutton(app.LeftOptions, 'push');
            app.ImportPointMassesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPointMassesButtonPushed, true);
            app.ImportPointMassesButton.Text = 'Import Point Masses';
            app.RightOptions = uipanel(app.PanelMesh);
            app.RightOptions.Title = 'RAO Options';
            app.ScaleLabel_3 = uilabel(app.RightOptions);
            app.ScaleLabel_3.Text = 'Gravity';
            app.Gravity = uieditfield(app.RightOptions, 'numeric');
            app.Gravity.Value = 9.81;
            app.ScaleLabel_4 = uilabel(app.RightOptions);
            app.ScaleLabel_4.Text = 'Water Density';
            app.WaterDensity = uieditfield(app.RightOptions, 'numeric');
            app.WaterDensity.Value = 997;
            app.ScaleLabel_5 = uilabel(app.RightOptions);
            app.ScaleLabel_5.Text = 'Wavelength';
            app.Wavelength = uieditfield(app.RightOptions, 'numeric');
            app.Wavelength.ValueChangedFcn = createCallbackFcn(app, @WavelengthValueChanged, true);
            app.Wavelength.Value = 106.4;
            app.ScaleLabel_6 = uilabel(app.RightOptions);
            app.ScaleLabel_6.Text = 'Wave Number';
            app.WaveNumber = uieditfield(app.RightOptions, 'numeric');
            app.WaveNumber.ValueChangedFcn = createCallbackFcn(app, @WaveNumberValueChanged, true);
            app.WaveNumber.Value = 0.059052;
            app.ImportPhisButton = uibutton(app.RightOptions, 'push');
            app.ImportPhisButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPhisButtonPushed, true);
            app.ImportPhisButton.Text = 'Import Φs';
            app.PeriodListEditFieldLabel = uilabel(app.RightOptions);
            app.PeriodListEditFieldLabel.Text = 'Period List';
            app.PeriodListEditField = uieditfield(app.RightOptions, 'text');
            app.PeriodListEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodListEditFieldValueChanged, true);
            app.PeriodListEditField.Value = '[1:3]';
            app.IncidentListLabel = uilabel(app.RightOptions);
            app.IncidentListLabel.Text = 'Incident θ List';
            app.IncidentThetaListEditField = uieditfield(app.RightOptions, 'text');
            app.IncidentThetaListEditField.ValueChangedFcn = createCallbackFcn(app, @IncidentThetaListEditFieldValueChanged, true);
            app.IncidentThetaListEditField.Value = '[0:pi/4:2*pi]';
            app.ScaleLabel_7 = uilabel(app.RightOptions);
            app.ScaleLabel_7.Text = 'Wave Amplitude';
            app.WaveAmplitude = uieditfield(app.RightOptions, 'numeric');
            app.WaveAmplitude.Value = 1;
            app.PanelConsole = uipanel(app.UIFigure);
            app.ConsoleLog = uitextarea(app.PanelConsole);
            app.ConsoleLog.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUV_FEA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end